classdef OSCILLOSCOPE_DEMO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EC3882PROYECTOSIIOSCILOSCOPIOUIFigure  matlab.ui.Figure
        OSCILLOSCOPEPanel  matlab.ui.container.Panel
        CH2A2Button        matlab.ui.control.Button
        CH3D1Button        matlab.ui.control.Button
        CH4D2Button        matlab.ui.control.Button
        CHANNELSLabel      matlab.ui.control.Label
        LampLabel          matlab.ui.control.Label
        Lamp               matlab.ui.control.Lamp
        Lamp_2Label        matlab.ui.control.Label
        Lamp_2             matlab.ui.control.Lamp
        Lamp_3Label        matlab.ui.control.Label
        Lamp_3             matlab.ui.control.Lamp
        Lamp_4Label        matlab.ui.control.Label
        Lamp_4             matlab.ui.control.Lamp
        VERTICALLabel      matlab.ui.control.Label
        HORIZONTALLabel    matlab.ui.control.Label
        CH1KnobLabel       matlab.ui.control.Label
        CH1Knob            matlab.ui.control.DiscreteKnob
        CH4KnobLabel       matlab.ui.control.Label
        CH4Knob            matlab.ui.control.DiscreteKnob
        CH2KnobLabel       matlab.ui.control.Label
        CH2Knob            matlab.ui.control.DiscreteKnob
        CH3KnobLabel       matlab.ui.control.Label
        CH3Knob            matlab.ui.control.DiscreteKnob
        SECDIVKnobLabel    matlab.ui.control.Label
        SECDIVKnob         matlab.ui.control.DiscreteKnob
        OFF1Button         matlab.ui.control.Button
        OFF2Button         matlab.ui.control.Button
        OFF3Button         matlab.ui.control.Button
        OFF4Button         matlab.ui.control.Button
        CH1A1Button        matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
        ENABLEButton       matlab.ui.control.Button
        Label              matlab.ui.control.Label
        Lamp_5             matlab.ui.control.Lamp
    end


    properties (Access = private)
        CH1_Data   % Variable de datos de canal 1 (1er analógico)
        CH2_Data   % Variable de datos de canal 2 (2do analógico)
        CH3_Data   % Variable de datos de canal 3 (1er digital)
        CH4_Data   % Variable de datos de canal 4 (2do digital)
        % s1 = serial('COM0','BaudRate',115200) % Puerto serial COM_0
        % s2 = serial('COM1','BaudRate',115200) % Puerto serial COM_1
        Fs;                                     % Frecuencia de muestreo        
        ctte_x = 1000;                          % Eje x 
        i = 0;                                  % Contador (prueba)        
        s = 1000;                               % Muestras (prueba)         
        a = tic;                                % Reloj para vel
        b;                                      % Variable de control de vel
        Vs = 10;                                % Mayor => menos tiempo de espera para mostrar datos en display
        y1,y2,y3,y4;                            % Funciones de prueba                                          
        h1,h2,h3,h4;                            % AnimatedLines de prueba                                     
        A1,A2,A3,A4;                            % Amplitudes de canales             
    end     

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Lamp.Color   = [1 0 0];
            app.Lamp_2.Color = [1 0 0];
            app.Lamp_3.Color = [1 0 0];
            app.Lamp_4.Color = [1 0 0]; 
            app.Lamp_5.Color = [1 0 0];
            app.A1 = 1;
            app.A2 = 1;
            app.A3 = 1;
            app.A4 = 1;  
            app.Fs = 25;
        end

        % Button pushed function: CH1A1Button
        function CH1A1ButtonPushed(app, event)
            app.Lamp.Color = [0 1 0];  
            clearpoints(app.h1);
            app.h1 = animatedline(app.UIAxes,'Color','b');
        end

        % Button pushed function: CH2A2Button
        function CH2A2ButtonPushed(app, event)
            app.Lamp_2.Color = [0 1 0];
            clearpoints(app.h2);
            app.h2 = animatedline(app.UIAxes,'Color','r');
        end

        % Button pushed function: OFF1Button
        function OFF1ButtonPushed(app, event)
            app.Lamp.Color = [1 0 0];
            clearpoints(app.h1);
            app.h1 = animatedline(app.UIAxes,'LineStyle','none');            
        end

        % Button pushed function: OFF2Button
        function OFF2ButtonPushed(app, event)
            app.Lamp_2.Color = [1 0 0];                       
            clearpoints(app.h2);
            app.h2 = animatedline(app.UIAxes,'LineStyle','none');              
        end

        % Button pushed function: ENABLEButton
        function ENABLEButtonPushed(app, event)
            app.Lamp_5.Color = [0 1 0];
            % ----------------------HABILITAR 4 CANALES----------------------------
            app.h1 = animatedline(app.UIAxes,'Color','w');
            app.h2 = animatedline(app.UIAxes,'Color','w'); 
            app.h3 = animatedline(app.UIAxes,'Color','w');
            app.h4 = animatedline(app.UIAxes,'Color','w'); 
            
            while app.i<app.ctte_x                        
            app.y1 = 10 + app.A1*sin(app.i/app.Fs);
            app.y2 = 7 + app.A2*cos(app.i/app.Fs);
            app.y3 = 4 + app.A3*square(app.i/app.Fs);
            app.y4 = 1 - app.A4*square(app.i/app.Fs);
            addpoints(app.h1,app.i,app.y1)
            addpoints(app.h2,app.i,app.y2)
            addpoints(app.h3,app.i,app.y3)
            addpoints(app.h4,app.i,app.y4)
            app.i = app.i + 1;
            app.b = toc(app.a);
                if app.b > (1/app.Vs)     % 'Velocidad' al actualizar pantalla
                    drawnow               % Update cada 1/Vs segundos     
                    app.a = tic;          % Resetea timer luego de update
                end
                if app.i>=app.ctte_x
                    app.i = 0;
                    clearpoints(app.h1);
                    clearpoints(app.h2);
                    clearpoints(app.h3);
                    clearpoints(app.h4);
                end    
            end            
        end

        % Button pushed function: CH3D1Button
        function CH3D1ButtonPushed(app, event)
            app.Lamp_3.Color = [0 1 0];  
            clearpoints(app.h3);
            app.h3 = animatedline(app.UIAxes,'Color','b');
        end

        % Button pushed function: CH4D2Button
        function CH4D2ButtonPushed(app, event)
            app.Lamp_4.Color = [0 1 0];  
            clearpoints(app.h4);
            app.h4 = animatedline(app.UIAxes,'Color','r');
        end

        % Button pushed function: OFF3Button
        function OFF3ButtonPushed(app, event)
            app.Lamp_3.Color = [1 0 0];                       
            clearpoints(app.h3);
            app.h3 = animatedline(app.UIAxes,'LineStyle','none'); 
        end

        % Button pushed function: OFF4Button
        function OFF4ButtonPushed(app, event)
            app.Lamp_4.Color = [1 0 0];                       
            clearpoints(app.h4);
            app.h4 = animatedline(app.UIAxes,'LineStyle','none');
        end

        % Value changed function: CH1Knob
        function CH1KnobValueChanged(app, event)
            app.A1 = app.CH1Knob.Value;            
            if isequal(app.A1,'0.3')
                app.A1 = 0.3;
            elseif isequal(app.A1,'3.0')
                app.A1 = 3; 
            else
                app.A1 = 1;
            end
        end

        % Value changed function: CH2Knob
        function CH2KnobValueChanged(app, event)
            app.A2 = app.CH2Knob.Value;
            if isequal(app.A2,'0.3')
                app.A2 = 0.3;
            elseif isequal(app.A2,'3.0')
                app.A2 = 3; 
            else
                app.A2 = 1;
            end
        end

        % Value changed function: CH3Knob
        function CH3KnobValueChanged(app, event)
            app.A3 = app.CH3Knob.Value;
            if isequal(app.A3,'0.3')
                app.A3 = 0.3;
            elseif isequal(app.A3,'3.0')
                app.A3 = 3; 
            else
                app.A3 = 1;
            end
        end

        % Value changed function: CH4Knob
        function CH4KnobValueChanged(app, event)
            app.A4 = app.CH4Knob.Value;
            if isequal(app.A4,'0.3')
                app.A4 = 0.3;
            elseif isequal(app.A4,'3.0')
                app.A4 = 3; 
            else
                app.A4 = 1;
            end
        end

        % Value changed function: SECDIVKnob
        function SECDIVKnobValueChanged(app, event)
            app.Fs = app.SECDIVKnob.Value;
            if isequal(app.Fs,'10')
                app.Fs = 10;
            elseif isequal(app.Fs,'50')
                app.Fs = 50; 
            elseif isequal(app.Fs,'100')
                app.Fs = 100;
            elseif isequal(app.Fs,'250')
                app.Fs = 250; 
            elseif isequal(app.Fs,'500')
                app.Fs = 500;
            elseif isequal(app.Fs,'1000')
                app.Fs = 1000;
            else
                app.Fs = 25;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EC3882PROYECTOSIIOSCILOSCOPIOUIFigure = uifigure;
            app.EC3882PROYECTOSIIOSCILOSCOPIOUIFigure.Name = 'EC-3882 PROYECTOS II: OSCILOSCOPIO';
            app.OSCILLOSCOPEPanel = uipanel(app.EC3882PROYECTOSIIOSCILOSCOPIOUIFigure);
            app.OSCILLOSCOPEPanel.Title = 'OSCILLOSCOPE';
            app.CH2A2Button = uibutton(app.OSCILLOSCOPEPanel, 'push');
            app.CH2A2Button.ButtonPushedFcn = createCallbackFcn(app, @CH2A2ButtonPushed, true);
            app.CH2A2Button.Text = 'CH2 (A2)';
            app.CH3D1Button = uibutton(app.OSCILLOSCOPEPanel, 'push');
            app.CH3D1Button.ButtonPushedFcn = createCallbackFcn(app, @CH3D1ButtonPushed, true);
            app.CH3D1Button.Text = 'CH3 (D1)';
            app.CH4D2Button = uibutton(app.OSCILLOSCOPEPanel, 'push');
            app.CH4D2Button.ButtonPushedFcn = createCallbackFcn(app, @CH4D2ButtonPushed, true);
            app.CH4D2Button.Text = 'CH4 (D2)';
            app.CHANNELSLabel = uilabel(app.OSCILLOSCOPEPanel);
            app.CHANNELSLabel.Text = '4 CHANNELS';
            app.LampLabel = uilabel(app.OSCILLOSCOPEPanel);
            app.LampLabel.Text = '';
            app.Lamp = uilamp(app.OSCILLOSCOPEPanel);
            app.Lamp_2Label = uilabel(app.OSCILLOSCOPEPanel);
            app.Lamp_2Label.Text = '';
            app.Lamp_2 = uilamp(app.OSCILLOSCOPEPanel);
            app.Lamp_3Label = uilabel(app.OSCILLOSCOPEPanel);
            app.Lamp_3Label.Text = '';
            app.Lamp_3 = uilamp(app.OSCILLOSCOPEPanel);
            app.Lamp_4Label = uilabel(app.OSCILLOSCOPEPanel);
            app.Lamp_4Label.Text = '';
            app.Lamp_4 = uilamp(app.OSCILLOSCOPEPanel);
            app.VERTICALLabel = uilabel(app.OSCILLOSCOPEPanel);
            app.VERTICALLabel.Text = 'VERTICAL';
            app.HORIZONTALLabel = uilabel(app.OSCILLOSCOPEPanel);
            app.HORIZONTALLabel.Text = 'HORIZONTAL';
            app.CH1KnobLabel = uilabel(app.OSCILLOSCOPEPanel);
            app.CH1KnobLabel.Text = 'CH1';
            app.CH1Knob = uiknob(app.OSCILLOSCOPEPanel, 'discrete');
            app.CH1Knob.Items = {'0.3', '1.0', '3.0'};
            app.CH1Knob.ValueChangedFcn = createCallbackFcn(app, @CH1KnobValueChanged, true);
            app.CH1Knob.Value = '1.0';
            app.CH4KnobLabel = uilabel(app.OSCILLOSCOPEPanel);
            app.CH4KnobLabel.Text = 'CH4';
            app.CH4Knob = uiknob(app.OSCILLOSCOPEPanel, 'discrete');
            app.CH4Knob.Items = {'0.3', '1.0', '3.0'};
            app.CH4Knob.ValueChangedFcn = createCallbackFcn(app, @CH4KnobValueChanged, true);
            app.CH4Knob.Value = '1.0';
            app.CH2KnobLabel = uilabel(app.OSCILLOSCOPEPanel);
            app.CH2KnobLabel.Text = 'CH2';
            app.CH2Knob = uiknob(app.OSCILLOSCOPEPanel, 'discrete');
            app.CH2Knob.Items = {'0.3', '1.0', '3.0'};
            app.CH2Knob.ItemsData = {'0.3', '1.0', '3.0'};
            app.CH2Knob.ValueChangedFcn = createCallbackFcn(app, @CH2KnobValueChanged, true);
            app.CH2Knob.Value = '1.0';
            app.CH3KnobLabel = uilabel(app.OSCILLOSCOPEPanel);
            app.CH3KnobLabel.Text = 'CH3';
            app.CH3Knob = uiknob(app.OSCILLOSCOPEPanel, 'discrete');
            app.CH3Knob.Items = {'0.3', '1.0', '3.0'};
            app.CH3Knob.ItemsData = {'0.3', '1.0', '3.0'};
            app.CH3Knob.ValueChangedFcn = createCallbackFcn(app, @CH3KnobValueChanged, true);
            app.CH3Knob.Value = '1.0';
            app.SECDIVKnobLabel = uilabel(app.OSCILLOSCOPEPanel);
            app.SECDIVKnobLabel.Text = 'SEC/DIV';
            app.SECDIVKnob = uiknob(app.OSCILLOSCOPEPanel, 'discrete');
            app.SECDIVKnob.Items = {'10', '25', '50', '100', '250', '500', '1000'};
            app.SECDIVKnob.ValueChangedFcn = createCallbackFcn(app, @SECDIVKnobValueChanged, true);
            app.SECDIVKnob.Value = '25';
            app.OFF1Button = uibutton(app.OSCILLOSCOPEPanel, 'push');
            app.OFF1Button.ButtonPushedFcn = createCallbackFcn(app, @OFF1ButtonPushed, true);
            app.OFF1Button.Text = 'OFF 1';
            app.OFF2Button = uibutton(app.OSCILLOSCOPEPanel, 'push');
            app.OFF2Button.ButtonPushedFcn = createCallbackFcn(app, @OFF2ButtonPushed, true);
            app.OFF2Button.Text = 'OFF 2';
            app.OFF3Button = uibutton(app.OSCILLOSCOPEPanel, 'push');
            app.OFF3Button.ButtonPushedFcn = createCallbackFcn(app, @OFF3ButtonPushed, true);
            app.OFF3Button.Text = 'OFF 3';
            app.OFF4Button = uibutton(app.OSCILLOSCOPEPanel, 'push');
            app.OFF4Button.ButtonPushedFcn = createCallbackFcn(app, @OFF4ButtonPushed, true);
            app.OFF4Button.Text = 'OFF 4';
            app.CH1A1Button = uibutton(app.OSCILLOSCOPEPanel, 'push');
            app.CH1A1Button.ButtonPushedFcn = createCallbackFcn(app, @CH1A1ButtonPushed, true);
            app.CH1A1Button.Text = 'CH1 (A1)';
            app.UIAxes = uiaxes(app.OSCILLOSCOPEPanel);
            title(app.UIAxes, 'DISPLAY')
            app.UIAxes.XLim = [0 1000];
            app.UIAxes.YLim = [0 13];
            app.UIAxes.LineStyleOrder = {'- '};
            app.UIAxes.XTickLabel = {''; ''};
            app.ENABLEButton = uibutton(app.OSCILLOSCOPEPanel, 'push');
            app.ENABLEButton.ButtonPushedFcn = createCallbackFcn(app, @ENABLEButtonPushed, true);
            app.ENABLEButton.Text = 'ENABLE';
            app.Label = uilabel(app.OSCILLOSCOPEPanel);
            app.Label.Text = '';
            app.Lamp_5 = uilamp(app.OSCILLOSCOPEPanel);
        end
    end

    methods (Access = public)

        % Construct app
        function app = OSCILLOSCOPE_DEMO

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EC3882PROYECTOSIIOSCILOSCOPIOUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EC3882PROYECTOSIIOSCILOSCOPIOUIFigure)
        end
    end
end